classdef prj8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_4            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
        SelectFolderButton  matlab.ui.control.Button
        XEditFieldLabel     matlab.ui.control.Label
        XEditField          matlab.ui.control.NumericEditField
        YEditFieldLabel     matlab.ui.control.Label
        YEditField          matlab.ui.control.NumericEditField
        ZEditFieldLabel     matlab.ui.control.Label
        ZEditField          matlab.ui.control.NumericEditField
        XEditField_2Label   matlab.ui.control.Label
        XEditField_2        matlab.ui.control.NumericEditField
        YEditField_2Label   matlab.ui.control.Label
        YEditField_2        matlab.ui.control.NumericEditField
        ZEditField_2Label   matlab.ui.control.Label
        ZEditField_2        matlab.ui.control.NumericEditField
        PointLabel          matlab.ui.control.Label
        PlaneLabel          matlab.ui.control.Label
        ShowPlaneButton     matlab.ui.control.Button
        CoronalSlider_2     matlab.ui.control.Slider
        AxialSlider_2       matlab.ui.control.Slider
        SagittalSlider_3    matlab.ui.control.Slider
        ResetButton         matlab.ui.control.Button
        AxialSlider_3       matlab.ui.control.Slider
        SagittalSlider_2    matlab.ui.control.Slider
        CoronalSlider_4     matlab.ui.control.Slider
        AxialLabel          matlab.ui.control.Label
        CoronalLabel        matlab.ui.control.Label
        SagittalLabel       matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function ImageShow(app,D,r,s,c,r2,s2,c2,r3,s3,c3)
            plot([1],[1],'r','Parent',app.UIAxes_3)
            plot([1],[2],'r','Parent',app.UIAxes_4)
            plot([1],[2],'r','Parent',app.UIAxes_2)
            [value1,value2,value0]=size(D);
            imshow(D(:,:,s),[],'Parent',app.UIAxes_4);
            Q=D(r,:,:);Q=squeeze(Q);
            if size(Q,1)>size(Q,2)
                Q=Q';Q=flipud(Q);
            end
            imshow(Q,[],'Parent',app.UIAxes_2);
            Q=D(:,c,:);Q=squeeze(Q);
            if size(Q,1)>size(Q,2)
                Q=Q';Q=flipud(Q);
            end
            
            imshow(Q,[],'Parent',app.UIAxes_3);
            %             plot([1 value1]+c2,[value2,1],'g','Parent',app.UIAxes_5);
            hold(app.UIAxes_4,'on')
            plot([1 value2],value1-[r3 r3],'r','Parent',app.UIAxes_4);
            plot([c3 c3],[1 value1],'g','Parent',app.UIAxes_4);
            hold(app.UIAxes_3,'on')
            plot([1 value2],value1-[r2 r2],'r','Parent',app.UIAxes_3);
            plot([value2*s3/value0 value2*s3/value0],[1 value2],'b','Parent',app.UIAxes_3);
            hold(app.UIAxes_2,'on')
            plot([value2*s2/value0 value2*s2/value0],[1 value2],'b','Parent',app.UIAxes_2);
            plot([1 value1],value2-[c2 c2],'g','Parent',app.UIAxes_2);
            axis(app.UIAxes_3,[1 2*value1 1 2*value2]);
            axis(app.UIAxes_4,[1 2*value1 1 2*value2]);
            axis(app.UIAxes_2,[1 2*value1 1 2*value2]);
            hold(app.UIAxes_4,'off')
            hold(app.UIAxes_3,'off')
            hold(app.UIAxes_2,'off')
            
        end
        
        function [B, XData, YData, ZData] = obliqueslice2(V, point, normal)
            
            outputsize='limit';
            fillValue=0;
            method='linear';
            V=double(V);
            % [V, point, normal, method, outputsize, fillValue, catConverter] = parse_inputs(varargin{:});
            originalClass = class(V);
            [numRows, numCols, numChannels] = size(V);
            sz = size(V);
            chInd = 0;
            if ~isa(V,'double')
                sz = single(sz);
                normal = single(normal);
                chInd = single(chInd);
            end
            
            % Initial normal vector
            intialNormVector = [0 0 1];
            
            % Unit normal vector
            unitNormalVector = normal/norm(normal);
            
            % Compute axis of rotation
            if isequal(intialNormVector, unitNormalVector)
                W = unitNormalVector;
            else
                W = cross(intialNormVector, unitNormalVector);
                W = W/norm(W);
                W(isnan(W)) = eps;
            end
            
            % Compute angle of rotation in radians
            angle = acos(dot(intialNormVector, unitNormalVector));
            
            % Quaternion rotation matrix
            t_quat = quat_matrix(W, -angle);
            
            % Quaternion rotation
            T = affine3d(t_quat);
            planeSize = 3*max(sz);
            numRows1 = planeSize;
            numCols1 = planeSize;
            
            % X,Y and Z coordinates of a plane with origin as the center
            [xp,yp,zp] = meshgrid(round(-numCols1/2):1:round(numCols1/2), round(-numRows1/2):1:round(numRows1/2), chInd);
            
            % Rotate coordinates of a plane using transformation matrix T
            [xr,yr,zr] = transformPointsForward(T,xp,yp,zp);
            
            % Shift user input point, relative to input volume having origin as center
            point(1) = point(1)-round(numCols/2);
            point(2) = point(2)-round(numRows/2);
            point(3) = point(3)-round(numChannels/2);
            
            % Find the shortest distance between the plane that passes through input
            % point and origin
            D = -(unitNormalVector(1)*point(1)+unitNormalVector(2)*point(2)+...
                unitNormalVector(3)*point(3));
            
            % Translate a plane that passes from origin to input point
            xq = xr - D*unitNormalVector(1) + round(numCols/2);
            yq = yr - D*unitNormalVector(2) + round(numRows/2);
            zq = zr - D*unitNormalVector(3) + round(numChannels/2);
            
            % Obtain slice at desired coordinates using interpolation
            
            obliqueSlice = interp3(V,xq,yq,zq,method,fillValue);
            
            % Make bounding box around the data in oblique slice
            sliceMaskLimit = (xq>=1 & xq<=sz(2)) & (yq>=1 & yq<=sz(1)) & (zq>=1 & zq<=sz(3));
            B1 =  regionprops(sliceMaskLimit,'BoundingBox');
            if isempty(B1)
                croppedSlice = [];
                XData = [];
                YData = [];
                ZData = [];
            else
                sliceSize = round(B1.BoundingBox);
                rows = [sliceSize(2),sliceSize(2)+sliceSize(4)-1];
                cols = [sliceSize(1),sliceSize(1)+sliceSize(3)-1];
                sliceCenter = ([(rows(1)+rows(2))/2, (cols(1)+cols(2))/2]);
                if (strcmp(outputsize,'limit'))
                    % OutputSize 'limit'
                    croppedSlice = obliqueSlice(rows(1):rows(2),cols(1):cols(2));
                    XData  = xq(rows(1):rows(2),cols(1):cols(2));
                    YData  = yq(rows(1):rows(2),cols(1):cols(2));
                    ZData  = zq(rows(1):rows(2),cols(1):cols(2));
                else
                    % OutputSize 'full'
                    
                    % Consider center of volume as a point
                    point = floor([sz(1)/2,sz(2)/2,sz(3)/2]);
                    % Translate the plane, so that the center of plane becomes center of volume
                    XData1 = xr+point(1);
                    YData1 = yr+point(2);
                    ZData1 = zr+point(3);
                    % create a mask for oblique slice
                    sliceMaskFull= (XData1>=1 & XData1<=sz(2)) & (YData1>=1 & YData1<=sz(1)) & (ZData1>=1 & ZData1<=sz(3));
                    % Make bounding box around the data in oblique slice
                    B1 =  regionprops(sliceMaskFull,'BoundingBox');
                    sliceSize = round(B1.BoundingBox);
                    rowInd = [sliceSize(2),sliceSize(2)+sliceSize(4)-1];
                    colInd = [sliceSize(1),sliceSize(1)+sliceSize(3)-1];
                    numRows = rowInd(2)-rowInd(1)+1;
                    numCols = colInd(2)-colInd(1)+1;
                    rowIndFull = floor([sliceCenter(1)-((numRows-1)/2) sliceCenter(1)+((numRows+1)/2)-1]);
                    colIndFull = floor([sliceCenter(2)-((numCols-1)/2) sliceCenter(2)+((numCols+1)/2)-1]);
                    % Make size of the output slice same as the slice that passes through
                    % center of volume with given normal
                    croppedSlice = obliqueSlice(rowIndFull(1):rowIndFull(2), colIndFull(1):colIndFull(2));
                    XData  = xq(rowIndFull(1):rowIndFull(2), colIndFull(1):colIndFull(2));
                    YData  = yq(rowIndFull(1):rowIndFull(2), colIndFull(1):colIndFull(2));
                    ZData  = zq(rowIndFull(1):rowIndFull(2), colIndFull(1):colIndFull(2));
                end
            end
            
            % if ~isempty(catConverter)
            %     B = catConverter.numeric2Categorical(croppedSlice);
            % else
            B = cast(croppedSlice,originalClass);
            B=uint8(B);
            % end
        end
        
        
        function t = quat_matrix(W, ANGLE)
            
            a_x = W(1,1);
            a_y = W(1,2);
            a_z = W(1,3);
            
            c = cos(ANGLE);
            s = sin(ANGLE);
            
            t1 = c + a_x^2*(1-c);
            t2 = a_x*a_y*(1-c) - a_z*s;
            t3 = a_x*a_z*(1-c) + a_y*s;
            t4 = a_y*a_x*(1-c) + a_z*s;
            t5 = c + a_y^2*(1-c);
            t6 = a_y*a_z*(1-c)-a_x*s;
            t7 = a_z*a_x*(1-c)-a_y*s;
            t8 = a_z*a_y*(1-c)+a_x*s;
            t9 = c+a_z^2*(1-c);
            
            t = [t1 t2 t3 0
                t4 t5 t6 0
                t7 t8 t9 0
                0  0  0  1];
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ShowPlaneButton.Visible='off';
            app.XEditField.Visible='off';
            app.YEditField.Visible='off';
            app.ZEditField.Visible='off';
            app.XEditField_2.Visible='off';
            app.YEditField_2.Visible='off';
            app.ZEditField_2.Visible='off';
            %             app.CoronalSlider.Visible='off';
            app.CoronalSlider_2.Visible='off';
            app.CoronalSlider_4.Visible='off';
            %             app.SagittalSlider.Visible='off';
            app.SagittalSlider_2.Visible='off';
            app.SagittalSlider_3.Visible='off';
            %             app.AxialSlider.Visible='off';
            app.AxialSlider_2.Visible='off';
            app.AxialSlider_3.Visible='off';
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            [pth]=uigetdir();
            Fles=ls(pth);
            global D value1 value2 value0 s r c s2 r2 c2 s3 r3 c3
            try
                for i=3:size(Fles,1)
                    try
                        [kk,map{i-2}]=dicomread([pth '\' Fles(i,:)]);
                    catch
                        [kk,map{i-2}]=imread([pth '\' Fles(i,:)]);
                    end
                    if size(kk,3)==1
                        D(:,:,i-2)=kk;
                    else
                        D(:,:,i-2)=rgb2gray(kk);
                    end
                end
                [value1,value2,value0]=size(D);
                r=round(value1/2);
                c=round(value2/2);
                s=round(value0/2);
                s2=s;r2=r;c2=c;
                s3=s;r3=r;c3=c;
                %                 app.SagittalSlider.Limits=[1 value1];
                %                app.SagittalSlider.Value=r;
                %               app.CoronalSlider.Limits=[1 value2];
                %              app.AxialSlider.Limits=[1 value0];
                %             app.CoronalSlider.Value=c;
                %            app.AxialSlider.Value=s;
                app.SagittalSlider_2.Limits=[1 value1];
                app.SagittalSlider_2.Value=r;
                app.CoronalSlider_2.Limits=[1 value2];
                app.AxialSlider_2.Limits=[1 value0];
                app.CoronalSlider_2.Value=c;
                app.AxialSlider_2.Value=s;
                app.SagittalSlider_3.Limits=[1 value1];
                app.SagittalSlider_3.Value=r;
                app.CoronalSlider_4.Limits=[1 value2];
                app.AxialSlider_3.Limits=[1 value0];
                app.CoronalSlider_4.Value=c;
                app.AxialSlider_3.Value=s;
                ImageShow(app,D,r,s,c,r,s,c,r,s,c)
                X=rand(1,6)*25+125;
                app.XEditField.Value=X(1);
                app.YEditField.Value=X(2);
                app.ZEditField.Value=X(3);
                app.XEditField_2.Value=X(4);
                app.YEditField_2.Value=X(5);
                app.ZEditField_2.Value=X(6);
                point=X(1:3);plane=X(4:6);
                I2=obliqueslice2(D,point,plane);
                imshow(I2,[],'parent',app.UIAxes);
                app.ShowPlaneButton.Visible='on';
                app.XEditField.Visible='on';
                app.YEditField.Visible='on';
                app.ZEditField.Visible='on';
                app.XEditField_2.Visible='on';
                app.YEditField_2.Visible='on';
                app.ZEditField_2.Visible='on';
                app.XEditField_2.Visible='on';
                app.YEditField_2.Visible='on';
                app.ZEditField_2.Visible='on';
                %                 app.CoronalSlider.Visible='on';
                app.CoronalSlider_2.Visible='on';
                app.CoronalSlider_4.Visible='on';
                %                 app.SagittalSlider.Visible='on';
                app.SagittalSlider_2.Visible='on';
                app.SagittalSlider_3.Visible='on';
                %                 app.AxialSlider.Visible='on';
                app.AxialSlider_2.Visible='on';
                app.AxialSlider_3.Visible='on';
            catch
                errordlg('Not Valid')
            end
            
        end

        % Callback function
        function SagittalSliderValueChanged(app, event)
            %             global  D value1 value2 value0 s r c
            %             r = round(app.SagittalSlider.Value);
            %             app.SagittalSlider_2.Value=r;
            %             app.SagittalSlider_3.Value=r;
            %             %             Q=D(r,:,:);Q=squeeze(Q);
            %             %             if size(Q,1)>size(Q,2)
            %             %                 Q=Q';Q=flipud(Q);
            %             %             end
            %             %             imshow(Q,[],'Parent',app.UIAxes_2);
            %             %             imshow(D(:,:,end),[],'parent',app.UIAxes_5)
            %             ImageShow(app,D,r,s,c)
            
        end

        % Callback function
        function CoronalSliderValueChanged(app, event)
            %             global  D value1 value2 value0 s r c
            %             c = round(app.CoronalSlider.Value);
            %             app.CoronalSlider_2.Value=c;
            %             app.CoronalSlider_4.Value=c;
            %             %             Q=D(:,c,:);Q=squeeze(Q);
            %             %             if size(Q,1)>size(Q,2)
            %             %                 Q=Q';Q=flipud(Q);
            %             %             end
            %             %             imshow(Q,[],'Parent',app.UIAxes_3);
            %             %             imshow(D(:,:,end),[],'parent',app.UIAxes_5)
            %             ImageShow(app,D,r,s,c)
            
        end

        % Callback function
        function MPRButtonPushed(app, event)
            
        end

        % Callback function
        function AxialSliderValueChanged(app, event)
            %             global  D value1 value2 value0 s r c
            %             s = round(app.AxialSlider.Value);
            %             app.AxialSlider_2.Value=s;
            %             app.AxialSlider_3.Value=s;
            %             %             imshow(D(:,:,s),[],'Parent',app.UIAxes_4);
            %             %             imshow(D(:,:,end),[],'parent',app.UIAxes_5)
            %             ImageShow(app,D,r,s,c)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear global
        end

        % Button pushed function: ShowPlaneButton
        function ShowPlaneButtonPushed(app, event)
            
            global D
            try
                X(1)=app.XEditField.Value;
                X(2)=app.YEditField.Value;
                X(3)=app.ZEditField.Value;
                X(4)=app.XEditField_2.Value;
                X(5)=app.YEditField_2.Value;
                X(6)=app.ZEditField_2.Value;
                point=X(1:3);plane=X(4:6);
                I2=obliqueslice2(D,point,plane);
                imshow(I2,[],'parent',app.UIAxes);
            catch
                errordlg('Please Enter the Data Set First')
            end
        end

        % Callback function
        function CoronalSlider_3ValueChanging(app, event)
            % global  D value1 value2 value0 s r c
            %             c = round(app.CoronalSlider_3.Value);
            %             app.CoronalSlider_2.Value=c;
            %             app.CoronalSlider_4.Value=c;
            %             %             Q=D(:,c,:);Q=squeeze(Q);
            %             %             if size(Q,1)>size(Q,2)
            %             %                 Q=Q';Q=flipud(Q);
            %             %             end
            %             %             imshow(Q,[],'Parent',app.UIAxes_3);
            %             %             imshow(D(:,:,end),[],'parent',app.UIAxes_5)
            %             ImageShow(app,D,r,s,c)
            %
        end

        % Value changed function: AxialSlider_2
        function AxialSlider_2ValueChanged(app, event)
            global  D s r c s2 r2 c2 s3 r3 c3
            s2 = round(app.AxialSlider_2.Value);
            s=s2;
            %             app.AxialSlider.Value=s;
            ImageShow(app,D,r,s,c,r2,s2,c3,r3,s3,c2)
            %             imshow(D(:,:,s),[],'Parent',app.UIAxes_4);
            %             imshow(D(:,:,end),[],'parent',app.UIAxes_5)
            
            
        end

        % Value changed function: AxialSlider_3
        function AxialSlider_3ValueChanged(app, event)
            global  D s r c s2 r2 c2 s3 r3 c3
            s3 = round(app.AxialSlider_3.Value);
            s=s3;
            app.AxialSlider_2.Value=s;
            %             app.AxialSlider.Value=s;
            ImageShow(app,D,r,s,c,r2,s2,c3,r3,s3,c2)
            s=s3;
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            delete(app)
            clear global
            run('prj8.mlapp')
        end

        % Value changed function: CoronalSlider_2
        function CoronalSlider_2ValueChanged(app, event)
            global  D s r c s2 r2 c2 s3 r3 c3
            c2 = round(app.CoronalSlider_2.Value);c=c2;
            %             app.CoronalSlider.Value=c;
            %             app.CoronalSlider_4.Value=c;
            ImageShow(app,D,r,s,c,r2,s2,c3,r2,s2,c2)


        end

        % Value changed function: SagittalSlider_2
        function SagittalSlider_2ValueChanged(app, event)
            global  D s r c s2 r2 c2 s3 r3 c3
            r2 = round(app.SagittalSlider_2.Value);
            r=r2;
            %             app.SagittalSlider.Value=r;
            ImageShow(app,D,r,s,c,r2,s2,c3,r3,s3,c2)
            
            
        end

        % Value changed function: SagittalSlider_3
        function SagittalSlider_3ValueChanged(app, event)
            global  D s r c s2 r2 c2 s3 r3 c3
            r3 = round(app.SagittalSlider_3.Value);
            %             app.SagittalSlider.Value=r;
            %             r3=r;
            ImageShow(app,D,r,s,c,r2,s2,c3,r3,s3,c2)
            r=r3;
            
        end

        % Value changed function: CoronalSlider_4
        function CoronalSlider_4ValueChanged(app, event)
            global D s r c s2 r2 c2 s3 r3 c3
            c3 = round(app.CoronalSlider_4.Value);
            c=c3;
            %             app.CoronalSlider_2.Value=c;
            %             app.CoronalSlider.Value=c;
            %             Q=D(:,c,:);Q=squeeze(Q);
            %             if size(Q,1)>size(Q,2)
            %                 Q=Q';Q=flipud(Q);
            %             end
            %             imshow(Q,[],'Parent',app.UIAxes_3);
            %             imshow(D(:,:,end),[],'parent',app.UIAxes_5)
            ImageShow(app,D,r,s,c,r2,s2,c3,r3,s3,c2)
            c=c3;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PointLabel = uilabel(app.UIFigure);
            app.PointLabel.Text = 'Point';
            app.PlaneLabel = uilabel(app.UIFigure);
            app.PlaneLabel.Text = 'Plane';
            app.ShowPlaneButton = uibutton(app.UIFigure, 'push');
            app.ShowPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPlaneButtonPushed, true);
            app.ShowPlaneButton.Text = 'Show Plane';
            app.CoronalSlider_2 = uislider(app.UIFigure);
            app.CoronalSlider_2.ValueChangedFcn = createCallbackFcn(app, @CoronalSlider_2ValueChanged, true);
            app.AxialSlider_2 = uislider(app.UIFigure);
            app.AxialSlider_2.ValueChangedFcn = createCallbackFcn(app, @AxialSlider_2ValueChanged, true);
            app.SagittalSlider_3 = uislider(app.UIFigure);
            app.SagittalSlider_3.ValueChangedFcn = createCallbackFcn(app, @SagittalSlider_3ValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AxialSlider_3 = uislider(app.UIFigure);
            app.AxialSlider_3.ValueChangedFcn = createCallbackFcn(app, @AxialSlider_3ValueChanged, true);
            app.SagittalSlider_2 = uislider(app.UIFigure);
            app.SagittalSlider_2.ValueChangedFcn = createCallbackFcn(app, @SagittalSlider_2ValueChanged, true);
            app.CoronalSlider_4 = uislider(app.UIFigure);
            app.CoronalSlider_4.ValueChangedFcn = createCallbackFcn(app, @CoronalSlider_4ValueChanged, true);
            app.AxialLabel = uilabel(app.UIFigure);
            app.AxialLabel.Text = 'Axial ';
            app.CoronalLabel = uilabel(app.UIFigure);
            app.CoronalLabel.Text = 'Coronal';
            app.SagittalLabel = uilabel(app.UIFigure);
            app.SagittalLabel.Text = 'Sagittal';
        end
    end

    methods (Access = public)

        % Construct app
        function app = prj8

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end